classdef NewGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PlayAgainButton         matlab.ui.control.Button
        UnfortunatelyyouhavefailedtosavetheplanetonthisoccasionLabel  matlab.ui.control.Label
        FinalTextDisplay_2      matlab.ui.control.TextArea
        Background_2            matlab.ui.control.EditField
        CongratulationsYouhavesuccessfullysavedtheplanetLabel  matlab.ui.control.Label
        FinalTextDisplay        matlab.ui.control.TextArea
        Background              matlab.ui.control.EditField
        GameDescriptionLabel    matlab.ui.control.Label
        gameinstructions        matlab.ui.control.Label
        detonateLabel           matlab.ui.control.Label
        EnterbuttonLabel        matlab.ui.control.Label
        leftshiftLabel          matlab.ui.control.Label
        rightshiftLabel         matlab.ui.control.Label
        enterkey                matlab.ui.control.Image
        leftarrow               matlab.ui.control.Image
        rightarrow              matlab.ui.control.Image
        ProceedButton           matlab.ui.control.Button
        Bombdown                matlab.ui.control.Button
        Bombup                  matlab.ui.control.Button
        Bullets1BombLabel       matlab.ui.control.Label
        DeatFighterThebatLabel  matlab.ui.control.Label
        NukeImage               matlab.ui.control.Image
        EXCHANGESTORELabel      matlab.ui.control.Label
        BombsEditFieldLabel     matlab.ui.control.Label
        BombsEditField          matlab.ui.control.NumericEditField
        BulletsEditFieldLabel   matlab.ui.control.Label
        BulletsEditField        matlab.ui.control.NumericEditField
        STARTButton             matlab.ui.control.Button
        DisplayScreen           matlab.ui.control.Image
        loserLabel              matlab.ui.control.Label
        winnerLabel             matlab.ui.control.Label
        EmptyHeart1             matlab.ui.control.Image
        EmptyHeart2             matlab.ui.control.Image
        EmptyHeart3             matlab.ui.control.Image
        RedHeart3               matlab.ui.control.Image
        RedHeart2               matlab.ui.control.Image
        RedHeart1               matlab.ui.control.Image
        DETONATEButton          matlab.ui.control.Button
        Timer                   matlab.ui.control.NumericEditField
        Top                     matlab.ui.control.EditField
        explo4                  matlab.ui.control.Image
        explo3                  matlab.ui.control.Image
        explo2                  matlab.ui.control.Image
        explo1                  matlab.ui.control.Image
        Fireball4               matlab.ui.control.Image
        Fireball3               matlab.ui.control.Image
        Fireball2               matlab.ui.control.Image
        Fireball1               matlab.ui.control.Image
        Redline                 matlab.ui.control.Image
        Spaceship               matlab.ui.control.Image
        laser                   matlab.ui.control.Image
        Space                   matlab.ui.control.Image
        deadnesscounter         matlab.ui.control.NumericEditField
        DeadnessEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ans % Description
        callingapp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, bulletload2, datafrombulletload2, moredatafrombulletload2)
  app.callingapp=bulletload2;
 app.BulletsEditField.Value=datafrombulletload2

%app.BulletsEditField.Value=60
app.UnfortunatelyyouhavefailedtosavetheplanetonthisoccasionLabel.Visible='off'
app.CongratulationsYouhavesuccessfullysavedtheplanetLabel.Visible='off'
app.FinalTextDisplay.Visible='off'
app.FinalTextDisplay_2.Visible='off'
app.PlayAgainButton.Visible='off'
app.Background.Visible='off'
app.Background_2.Visible='off'
app.loserLabel.Visible='off';
app.winnerLabel.Visible=['off'];
app.STARTButton.Visible='off';
app.EnterbuttonLabel.Visible='off';
app.enterkey.Visible='off';
app.rightarrow.Visible='off';
app.rightshiftLabel.Visible='off';
app.leftarrow.Visible=['off'];
app.leftshiftLabel.Visible='off';
app.detonateLabel.Visible='off';
app.gameinstructions.Visible='off';
app.GameDescriptionLabel.Visible='off';


        end

        % Callback function: BombsEditField, UIFigure, UIFigure
        function UIFigureKeyPress(app, event)
            if strcmpi(event.EventName, 'Close')
                delete(app.UIFigure)
            else
                disp(event.Key)
            end
  if app.Timer.Value>=0.5&app.BulletsEditField.Value>=1;

               key = event.Key;
            laserup=[0 75 0 0];
            cutoff=[470 0 0 0];
            cannonposition=app.Spaceship.Position;
            location=cannonposition(1);
            switch key
                case 'rightarrow'
                  
                    app.Spaceship.Position=app.Spaceship.Position+[200 0 0 0];
                    %app.laser.Position=app.laser.Position+[200 0 0 0]
                    app.laser.Position=app.Spaceship.Position;
                    if location>600;
                        app.Spaceship.Position=[50 25 100 100];
                        app.laser.Position=[50 25 100 100];
                     
                    end
                case 'leftarrow'
                
                    app.Spaceship.Position=app.Spaceship.Position-[200 0 0 0];
                    %app.laser.Position=app.laser.Position-[200 0 0 0]
                    app.laser.Position=app.Spaceship.Position;
                    if location<80
                        app.Spaceship.Position=[650 25 100 100];
                        app.laser.Position=[650 25 100 100];
                    end


                case 'return'
     
                    
                    if app.laser.Position(1)==app.Fireball1.Position(1)&app.laser.Position(2)<=app.Fireball1.Position(2)
                          app.BulletsEditField.Value=app.BulletsEditField.Value-1
                          app.laser.Visible='on'
                          app.laser.Position=app.laser.Position+laserup;
                          pause(0.03)
                          app.laser.Position=app.Fireball1.Position-[0 30 0 0];
                          pause(0.03)
                          app.laser.Position=app.Fireball1.Position-[0 5 0 0];
                          pause(0.03)
                          app.laser.Position=app.Fireball1.Position;
                          pause(0.03)
                          [y,Fs]=audioread("blaster-2-81267.mp3");
                          sound(y,Fs)
                          app.Fireball1.Visible='off';
                          app.explo1.Position=app.Fireball1.Position;
                          app.explo1.Visible='on';
                          app.laser.Visible='off';

                          pause(0.1)
                          app.explo1.Visible='off';
                          app.Fireball1.Position=[50 500 100 100];
                          app.Fireball1.Visible='on';
                          app.laser.Position=app.Spaceship.Position
                        
                   
                elseif app.laser.Position(1)==app.Fireball2.Position(1)&app.laser.Position(2)<=app.Fireball2.Position(2)
                          app.BulletsEditField.Value=app.BulletsEditField.Value-1;
                          app.laser.Visible='on';
                          app.laser.Position=app.laser.Position+laserup;
                          pause(0.03)
                          app.laser.Position=app.Fireball2.Position-[0 5 0 0];
                          pause(0.03)
                          app.laser.Position=app.Fireball2.Position;
                          [y,Fs]=audioread("blaster-2-81267.mp3");
                          sound(y,Fs)

                          app.laser.Visible='off';
                          app.Fireball2.Visible='off';
                          app.explo2.Position=app.Fireball2.Position;
                          app.explo2.Visible='on';

                          pause(0.1)
                          app.explo2.Visible='off'
                          app.Fireball2.Position=[250 500 100 100];
                          app.Fireball2.Visible='on';
                          app.laser.Position=app.Spaceship.Position;
                     
                    elseif app.laser.Position(1)==app.Fireball3.Position(1)&app.laser.Position(2)<=app.Fireball3.Position(2)
                          app.BulletsEditField.Value=app.BulletsEditField.Value-1
                          app.laser.Visible='on'
                          app.laser.Position=app.laser.Position+laserup;
                          pause(0.03)
                          app.laser.Position=app.laser.Position+laserup;
                          pause(0.03)
                          app.laser.Position=app.Fireball3.Position-[0 5 0 0]
                          pause(0.03)
                          app.laser.Position=app.Fireball3.Position
                          [y,Fs]=audioread("blaster-2-81267.mp3");
                          sound(y,Fs)
                          app.laser.Visible='off'
                          app.Fireball3.Visible='off'
                          app.explo3.Position=app.Fireball3.Position
                          app.explo3.Visible='on'

                          pause(0.1)
                          app.explo3.Visible='off'
                          app.Fireball3.Position=[450 500 100 100]
                          app.Fireball3.Visible='on'
                          app.laser.Position=app.Spaceship.Position
                      
                   
                    elseif app.laser.Position(1)==app.Fireball4.Position(1)&app.laser.Position(2)<=app.Fireball4.Position(2)
                          app.BulletsEditField.Value=app.BulletsEditField.Value-1
                          app.laser.Visible='on'
                          app.laser.Position=app.laser.Position+laserup;
                          pause(0.03)
                          app.laser.Position=app.laser.Position+laserup;
                          pause(0.03)
                          app.laser.Position=app.Fireball4.Position-[0 5 0 0]
                          pause(0.03)
                          app.laser.Position=app.Fireball4.Position
                          [y,Fs]=audioread("blaster-2-81267.mp3");
                          sound(y,Fs)
                          app.laser.Visible='off'
                          app.Fireball4.Visible='off'
                          app.explo4.Position=app.Fireball4.Position
                          app.explo4.Visible='on'

                          pause(0.1)
                          app.explo4.Visible='off'
                          app.Fireball4.Position=[650 500 100 100]
                          app.Fireball4.Visible='on'
                          app.laser.Position=app.Spaceship.Position

                      end

            end
end

if app.Fireball1.Position(2)<=app.Spaceship.Position(2)
    app.Fireball1.Visible='off'
end
if app.Fireball2.Position(2)<=app.Spaceship.Position(2)
    app.Fireball2.Visible='off'
end
if app.Fireball3.Position(2)<=app.Spaceship.Position(2)
    app.Fireball3.Visible='off'
end
if app.Fireball4.Position(2)<=app.Spaceship.Position(2)
    app.Fireball4.Visible='off'
end

%if app.Fireball1.Position(2)<100|app.Fireball2.Position(2)<100|app.Fireball3.Position(2)<100|app.Fireball4.Position(2)<100


        end

        % Callback function
        function STARTButtonPushed(app, event)

        end

        % Callback function
        function CheckButtonPushed(app, event)
             answer=str2double(app.AnswerBox.Value)
             laserup=[0 75 0 0]
            cutoff=[470 0 0 0]
            cannonposition=app.Spaceship.Position
            location=cannonposition(1)
             if answer==app.ans
                   app.laser.Visible='on'
                    app.laser.Position=app.laser.Position+laserup;
                    pause(0.03)
                    app.laser.Position=app.laser.Position+laserup;
                    pause(0.03)
                    app.laser.Position=app.laser.Position+laserup;
                    pause(0.03 )
                    app.laser.Position=app.laser.Position+laserup;
                    pause(0.03)
             end
              figure(app.UIFigure)

        end

        % Value changed function: BulletsEditField
        function BulletsEditFieldValueChanged(app, event)
            value = app.BulletsEditField.Value;
             figure(app.UIFigure)
        end

        % Callback function
        function BombsEditFieldValueChanged(app, event)
            value = app.BombsEditField.Value;
             figure(app.UIFigure)
        end

        % Button pushed function: DETONATEButton
        function DETONATEButtonPushed(app, event)
if app.BombsEditField.Value>=1
app.BombsEditField.Value=app.BombsEditField.Value-1
app.explo1.Visible='on'
app.Fireball1.Visible='off'
app.explo1.Position=app.Fireball1.Position
app.explo2.Visible='on'
app.Fireball2.Visible='off'
app.explo2.Position=app.Fireball2.Position
app.explo3.Visible='on'
app.Fireball3.Visible='off'
app.explo3.Position=app.Fireball3.Position
app.explo4.Visible='on'
app.Fireball4.Visible='off'
app.explo4.Position=app.Fireball4.Position
pause(0.3)
app.explo1.Visible='off'
app.Fireball1.Position=[50 500 100 100]
app.Fireball1.Visible='on'
app.explo2.Visible='off'
app.Fireball2.Position=[250 500 100 100]
app.Fireball2.Visible='on'
app.explo3.Visible='off'
app.Fireball3.Position=[450 500 100 100]
app.Fireball3.Visible='on'
app.explo4.Visible='off'
app.Fireball4.Position=[650 500 100 100]
app.Fireball4.Visible='on'
[y,Fs]=audioread("small-explosion-129477.mp3");
                     sound(y,Fs)
end


            figure(app.UIFigure)
        end

        % Callback function
        function YesButtonPushed(app, event)

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed2(app, event)
                   %app.callingapp=bulletload2;
        % app.BulletsEditField.Value=datafrombulletload2
   figure(app.UIFigure)
app.BombsEditField.Visible='on'
app.DETONATEButton.Enable='on'
           app.BombsEditFieldLabel.Visible='on'
           app.BulletsEditField.Visible='on'
           app.BulletsEditFieldLabel.Visible='on'
           app.STARTButton.Visible='off'
           app.DisplayScreen.Visible='off'
           app.EXCHANGESTORELabel.Visible='off'
           app.NukeImage.Visible='off'
           app.DeatFighterThebatLabel.Visible='off'
           app.Bullets1BombLabel.Visible='off'
           app.Bombdown.Visible='off'
           app.Bombup.Visible='off'
           app.EnterbuttonLabel.Visible='off'
app.enterkey.Visible='off'
app.rightarrow.Visible='off'
app.rightshiftLabel.Visible='off'
app.leftarrow.Visible=['off']
app.leftshiftLabel.Visible='off'
app.detonateLabel.Visible='off'
            app.Timer.Value=30;
         %   app.EnemyAttack.Position=[650 500 100 100]

            app.BulletsEditFieldLabel.Position=[325,463,97,61]
            app.BulletsEditField.Position=[421,463,106,61]
            app.BombsEditFieldLabel.Position=[536,463,97,61]
            app.BombsEditField.Position=[632,463,106,61]
            app.DETONATEButton.Position=[138,463,178,61]
          app.Spaceship.Position=[250,25,100,100];
            app.Fireball1.Position=[50 500 100 100];
            app.Fireball2.Position=[250 500 100 100];
            app.Fireball3.Position=[450 500 100 100];
            app.Fireball4.Position=[650 500 100 100];
            app.explo1.Visible='off';
            app.explo2.Visible='off';
            app.explo3.Visible='off';
            app.explo4.Visible='off';
            app.explo1.Position=app.Fireball1.Position
            app.explo2.Position=app.Fireball2.Position
            app.explo3.Position=app.Fireball3.Position
            app.explo4.Position=app.Fireball4.Position
            app.laser.Visible='off'
            app.laser.Position=[250, 25,100,100];
            app.EmptyHeart1.Visible='off'
            app.EmptyHeart2.Visible='off'
            app.EmptyHeart3.Visible='off'
            app.winnerLabel.Visible='off'
            app.loserLabel.Visible='off'
            app.gameinstructions.Visible='off'
app.GameDescriptionLabel.Visible='off'
          %  app.STARTButton.Enable='on';
            height=app.Fireball1.Position(2);

if app.Fireball1.Position(2)>520
    app.Fireball1.Position=app.Fireball1.Position
end

                        timelimit=30;
timesup=0;
y=[0 1 0 0];
timerstart=tic;
while ~timesup && app.deadnesscounter.Value<3
pause(0.035);
timepassed=toc(timerstart);
app.Timer.Value=round(timelimit-timepassed);
    app.Fireball1.Position=app.Fireball1.Position-[0 5 0 0];
    app.Fireball2.Position=app.Fireball2.Position-[0 5 0 0];
    app.Fireball3.Position=app.Fireball3.Position-[0 5 0 0];
    app.Fireball4.Position=app.Fireball4.Position-[0 5 0 0]
timesup=app.Timer.Value<=0;
if app.Fireball1.Position(2)==30
    app.deadnesscounter.Value=app.deadnesscounter.Value+1
    app.Fireball1.Position=[50 500 100 100]
end
if app.Fireball2.Position(2)==30
    app.deadnesscounter.Value=app.deadnesscounter.Value+1
    app.Fireball2.Position=[250 500 100 100]
end
if app.Fireball3.Position(2)==30
    app.deadnesscounter.Value=app.deadnesscounter.Value+1
    app.Fireball3.Position=[450 500 100 100]
end
if app.Fireball4.Position(2)==30
    app.deadnesscounter.Value=app.deadnesscounter.Value+1
    app.Fireball4.Position=[650 500 100 100]
end

if app.deadnesscounter.Value==1
    app.EmptyHeart3.Visible='on'
    app.RedHeart3.Visible='off'
end
if app.deadnesscounter.Value==2
     app.EmptyHeart3.Visible='on'
    app.RedHeart3.Visible='off'
    app.EmptyHeart2.Visible=['on']
    app.RedHeart2.Visible='off'
end
if app.deadnesscounter.Value>=3
      app.EmptyHeart3.Visible='on'
    app.RedHeart3.Visible='off'
    app.EmptyHeart2.Visible=['on']
    app.RedHeart2.Visible='off'
    app.EmptyHeart1.Visible=['on' ]
    app.RedHeart1.Visible='off'

    
end
if app.Timer.Value==0&app.deadnesscounter.Value<3&app.deadnesscounter.Value>=0
    app.winnerLabel.Visible='on'
    app.FinalTextDisplay.Visible='on'
    app.CongratulationsYouhavesuccessfullysavedtheplanetLabel.Visible='on'
    app.Background.Visible='on'
    app.PlayAgainButton.Visible='on'
    [y,Fs]=audioread("game-level-complete-143022.mp3");
            sound(y,Fs)
elseif app.deadnesscounter.Value>=3
    app.loserLabel.Visible='on'
    app.FinalTextDisplay_2.Visible='on'
    app.UnfortunatelyyouhavefailedtosavetheplanetonthisoccasionLabel.Visible='on'
    app.Background_2.Visible='on'
    app.PlayAgainButton.Visible='on'
    [y,Fs]=audioread("8-bit-video-game-fail-version-2-145478.mp3")
                          sound(y,Fs)
      
end
end


    %This code generates random integers up to 9 to create a question that
    %can be solved
            
        end

        % Button pushed function: Bombup
        function BombupButtonPushed(app, event)
             if app.BulletsEditField.Value-3>0
            app.BombsEditField.Value=app.BombsEditField.Value+1
            app.BulletsEditField.Value=app.BulletsEditField.Value-3
         end
        end

        % Button pushed function: Bombdown
        function BombdownButtonPushed(app, event)
                   if app.BombsEditField.Value>0
            app.BombsEditField.Value=app.BombsEditField.Value-1
            app.BulletsEditField.Value=app.BulletsEditField.Value+3
         end
            
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            app.ProceedButton.Visible='off'
            app.DETONATEButton.Enable='off'
            app.STARTButton.Visible='on'
           app.DisplayScreen.Visible='off'
           app.EXCHANGESTORELabel.Visible='off'
           app.NukeImage.Visible='off'
           app.DeatFighterThebatLabel.Visible='off'
           app.Bullets1BombLabel.Visible='off'
           app.Bombdown.Visible='off'
           app.Bombup.Visible='off'
           app.BombsEditField.Visible='off'
           app.BombsEditFieldLabel.Visible='off'
           app.BulletsEditField.Visible='off'
           app.BulletsEditFieldLabel.Visible='off'
           app.EnterbuttonLabel.Visible='on'

app.enterkey.Visible='on'
app.rightarrow.Visible='on'
app.rightshiftLabel.Visible='on'
app.leftarrow.Visible=['on']
app.leftshiftLabel.Visible='on'
app.detonateLabel.Visible='on'
app.gameinstructions.Visible='on'
app.GameDescriptionLabel.Visible='on'
            app.Timer.Value=30;

            
         %   app.EnemyAttack.Position=[650 500 100 100]

            app.BulletsEditFieldLabel.Position=[325,463,97,61]
            app.BulletsEditField.Position=[421,463,106,61]
            app.BombsEditFieldLabel.Position=[536,463,97,61]
            app.BombsEditField.Position=[632,463,106,61]
            app.DETONATEButton.Position=[138,463,178,61]

                   app.Spaceship.Position=[250,25,100,100];
            app.Fireball1.Position=[50 500 100 100];
            app.Fireball2.Position=[250 500 100 100];
            app.Fireball3.Position=[450 500 100 100];
            app.Fireball4.Position=[650 500 100 100];
            app.explo1.Visible='off';
            app.explo2.Visible='off';
            app.explo3.Visible='off';
            app.explo4.Visible='off';
            app.laser.Visible='off'
        end

        % Button pushed function: PlayAgainButton
        function PlayAgainButtonPushed(app, event)
           delete(app)
            bulletload2

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.DeadnessEditFieldLabel = uilabel(app.UIFigure);
            app.DeadnessEditFieldLabel.Text = 'Deadness';
            app.deadnesscounter = uieditfield(app.UIFigure, 'numeric');
            app.Space = uiimage(app.UIFigure);
            app.Space.ImageSource = 'spacce.jpg';
            app.laser = uiimage(app.UIFigure);
            app.laser.ImageSource = 'Screenshot_2023-04-16_193741-removebg-preview.png';
            app.Spaceship = uiimage(app.UIFigure);
            app.Spaceship.ImageSource = 'space-cannon-cannon-space-ship-icon-11553438283aovopadre3-removebg-preview (3).png';
            app.Redline = uiimage(app.UIFigure);
            app.Redline.ImageSource = 'updated reline.jpg';
            app.Fireball1 = uiimage(app.UIFigure);
            app.Fireball1.ImageSource = 'fireball-removebg-preview.png';
            app.Fireball2 = uiimage(app.UIFigure);
            app.Fireball2.ImageSource = 'fireball-removebg-preview.png';
            app.Fireball3 = uiimage(app.UIFigure);
            app.Fireball3.ImageSource = 'fireball-removebg-preview.png';
            app.Fireball4 = uiimage(app.UIFigure);
            app.Fireball4.ImageSource = 'fireball-removebg-preview.png';
            app.explo1 = uiimage(app.UIFigure);
            app.explo1.ImageSource = 'png-clipart-cartoon-explosion-others-comics-orange-removebg-preview.png';
            app.explo2 = uiimage(app.UIFigure);
            app.explo2.ImageSource = 'png-clipart-cartoon-explosion-others-comics-orange-removebg-preview.png';
            app.explo3 = uiimage(app.UIFigure);
            app.explo3.ImageSource = 'png-clipart-cartoon-explosion-others-comics-orange-removebg-preview.png';
            app.explo4 = uiimage(app.UIFigure);
            app.explo4.ImageSource = 'png-clipart-cartoon-explosion-others-comics-orange-removebg-preview.png';
            app.Top = uieditfield(app.UIFigure, 'text');
            app.Timer = uieditfield(app.UIFigure, 'numeric');
            app.DETONATEButton = uibutton(app.UIFigure, 'push');
            app.DETONATEButton.ButtonPushedFcn = createCallbackFcn(app, @DETONATEButtonPushed, true);
            app.DETONATEButton.Text = 'DETONATE';
            app.RedHeart1 = uiimage(app.UIFigure);
            app.RedHeart1.ImageSource = 'red_heart-removebg-preview.png';
            app.RedHeart2 = uiimage(app.UIFigure);
            app.RedHeart2.ImageSource = 'red_heart-removebg-preview.png';
            app.RedHeart3 = uiimage(app.UIFigure);
            app.RedHeart3.ImageSource = 'red_heart-removebg-preview.png';
            app.EmptyHeart3 = uiimage(app.UIFigure);
            app.EmptyHeart3.ImageSource = 'empty_heart-removebg-preview.png';
            app.EmptyHeart2 = uiimage(app.UIFigure);
            app.EmptyHeart2.ImageSource = 'empty_heart-removebg-preview.png';
            app.EmptyHeart1 = uiimage(app.UIFigure);
            app.EmptyHeart1.ImageSource = 'empty_heart-removebg-preview.png';
            app.winnerLabel = uilabel(app.UIFigure);
            app.winnerLabel.Text = 'winner';
            app.loserLabel = uilabel(app.UIFigure);
            app.loserLabel.Text = 'loser';
            app.DisplayScreen = uiimage(app.UIFigure);
            app.DisplayScreen.ImageSource = '8fc17ff53c7596f242fbd1d634ac747c-removebg-preview.png';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed2, true);
            app.STARTButton.Text = 'START';
            app.BulletsEditField = uieditfield(app.UIFigure, 'numeric');
            app.BulletsEditField.ValueChangedFcn = createCallbackFcn(app, @BulletsEditFieldValueChanged, true);
            app.BulletsEditFieldLabel = uilabel(app.UIFigure);
            app.BulletsEditFieldLabel.Text = 'Bullets';
            app.BombsEditField = uieditfield(app.UIFigure, 'numeric');
            app.BombsEditField.ValueChangedFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.BombsEditFieldLabel = uilabel(app.UIFigure);
            app.BombsEditFieldLabel.Text = 'Bombs';
            app.EXCHANGESTORELabel = uilabel(app.UIFigure);
            app.EXCHANGESTORELabel.Text = 'EXCHANGE STORE';
            app.NukeImage = uiimage(app.UIFigure);
            app.NukeImage.ImageSource = 'png-transparent-nuclear-radioactivity-toxic-hazard-radioactive-atomic-hazardous-nuke-materials-sign-thumbnail-removebg-preview.png';
            app.DeatFighterThebatLabel = uilabel(app.UIFigure);
            app.DeatFighterThebatLabel.Text = 'Deat Fighter, The Battle Rating you acquired in Stage 1 has automatically been converted into amunition. If you so wish, you may take this oppotunity to exchange for the Galactic Nuke: An all powerful bomb capable of destroying a full wave of meteors';
            app.Bullets1BombLabel = uilabel(app.UIFigure);
            app.Bullets1BombLabel.Text = '3 Bullets=1 Bomb';
            app.Bombup = uibutton(app.UIFigure, 'push');
            app.Bombup.ButtonPushedFcn = createCallbackFcn(app, @BombupButtonPushed, true);
            app.Bombup.Text = '+1';
            app.Bombdown = uibutton(app.UIFigure, 'push');
            app.Bombdown.ButtonPushedFcn = createCallbackFcn(app, @BombdownButtonPushed, true);
            app.Bombdown.Text = '-1';
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.rightarrow = uiimage(app.UIFigure);
            app.rightarrow.ImageSource = 'rightarrow.png';
            app.leftarrow = uiimage(app.UIFigure);
            app.leftarrow.ImageSource = 'leftarrow.png';
            app.enterkey = uiimage(app.UIFigure);
            app.enterkey.ImageSource = 'enter.png';
            app.rightshiftLabel = uilabel(app.UIFigure);
            app.rightshiftLabel.Text = 'Press this key to shift to the right';
            app.leftshiftLabel = uilabel(app.UIFigure);
            app.leftshiftLabel.Text = 'Press this key to shift to the left';
            app.EnterbuttonLabel = uilabel(app.UIFigure);
            app.EnterbuttonLabel.Text = 'Press this key to shoot a laser';
            app.detonateLabel = uilabel(app.UIFigure);
            app.detonateLabel.Text = 'Click this button to detonate the Bomb';
            app.gameinstructions = uilabel(app.UIFigure);
            app.gameinstructions.Text = 'Hearts inidcate the number of lives you have.  If a meteor surpasses the red barrier, a life will be lost. If you can maintain lives for the duration of the timer, you win!';
            app.GameDescriptionLabel = uilabel(app.UIFigure);
            app.GameDescriptionLabel.Text = 'Game Description';
            app.Background = uieditfield(app.UIFigure, 'text');
            app.FinalTextDisplay = uitextarea(app.UIFigure);
            app.FinalTextDisplay.Value = {'MISSION SUCCESS'};
            app.CongratulationsYouhavesuccessfullysavedtheplanetLabel = uilabel(app.UIFigure);
            app.CongratulationsYouhavesuccessfullysavedtheplanetLabel.Text = 'Congratulations! You have successfully saved the planet';
            app.Background_2 = uieditfield(app.UIFigure, 'text');
            app.FinalTextDisplay_2 = uitextarea(app.UIFigure);
            app.FinalTextDisplay_2.Value = {'MISSION FAILED'};
            app.UnfortunatelyyouhavefailedtosavetheplanetonthisoccasionLabel = uilabel(app.UIFigure);
            app.UnfortunatelyyouhavefailedtosavetheplanetonthisoccasionLabel.Text = 'Unfortunately, you have failed to save the planet on this occasion';
            app.PlayAgainButton = uibutton(app.UIFigure, 'push');
            app.PlayAgainButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAgainButtonPushed, true);
            app.PlayAgainButton.Text = 'Play Again';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewGame(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end