classdef bulletload2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CosmicCalculationsLabel    matlab.ui.control.Label
        whiteback                  matlab.ui.control.EditField
        Retry                      matlab.ui.control.Button
        Proceed                    matlab.ui.control.Button
        Battleratingmessage        matlab.ui.control.TextArea
        BulletsFinalCount          matlab.ui.control.NumericEditField
        FinalBattleRatingLabel     matlab.ui.control.Label
        FinalTextDisplay           matlab.ui.control.TextArea
        Background                 matlab.ui.control.EditField
        SELECTYOURDIFFICULTYLabel  matlab.ui.control.Label
        TYPEHERELabel              matlab.ui.control.Label
        randbluebox                matlab.ui.control.EditField
        Bullets23                  matlab.ui.control.Image
        Bullets22                  matlab.ui.control.Image
        Bullets20                  matlab.ui.control.Image
        Bullets21                  matlab.ui.control.Image
        Bullets19                  matlab.ui.control.Image
        Bullets16                  matlab.ui.control.Image
        Bullets18                  matlab.ui.control.Image
        Bullets15                  matlab.ui.control.Image
        Bullets14                  matlab.ui.control.Image
        Bullets13                  matlab.ui.control.Image
        Bullets11                  matlab.ui.control.Image
        Bullets12                  matlab.ui.control.Image
        Bullets17                  matlab.ui.control.Image
        Bullets10                  matlab.ui.control.Image
        NoSound                    matlab.ui.control.Image
        SoundOn                    matlab.ui.control.Image
        Bullets                    matlab.ui.control.Image
        Bullets8                   matlab.ui.control.Image
        Bullets7                   matlab.ui.control.Image
        Bullets6                   matlab.ui.control.Image
        Bullets4                   matlab.ui.control.Image
        Bullets5                   matlab.ui.control.Image
        Bullets3                   matlab.ui.control.Image
        Bullets2                   matlab.ui.control.Image
        Bullets9                   matlab.ui.control.Image
        WrongAnsImage              matlab.ui.control.Image
        GreenTick                  matlab.ui.control.Image
        Lamp1                      matlab.ui.control.Lamp
        Lamp2                      matlab.ui.control.Lamp
        Lamp3                      matlab.ui.control.Lamp
        Knob                       matlab.ui.control.DiscreteKnob
        x8Label                    matlab.ui.control.Label
        AnswerBox                  matlab.ui.control.EditField
        TypedAns                   matlab.ui.control.NumericEditField
        Bulletcount                matlab.ui.control.NumericEditField
        BattleRatingLabel          matlab.ui.control.Label
        Timer                      matlab.ui.control.NumericEditField
        StartButton                matlab.ui.control.Button
        TextBorder                 matlab.ui.control.EditField
        EditField                  matlab.ui.control.EditField
        EditField_2                matlab.ui.control.EditField
        SkipButton                 matlab.ui.control.Button
        EditField_3                matlab.ui.control.EditField
        Image                      matlab.ui.control.Image
        CheckButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        ans % This is the correct answer to the actual problem itself
      %  Bullet=0; % This indicates the score
        Bombs=0;
        answer; %this is the answer which the user has typed
        HighScore=0%This sets the best score to 0
        UserData
        key; % Description
        NewGame;
    end
    
    properties (Access = public)
        Bullet=0 % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, NewGame)
            app.EditField.Enable='off';
            app.CheckButton.Enable='off';
            app.TypedAns.Enable='off';
            app.Timer.Value=60;
            app.TypedAns.Visible='off';
            app.Retry.Visible='off';
            app.StartButton.Enable='on';
            app.StartButton.Visible='on';
            app.Bulletcount.Value=0;
            app.CheckButton.Enable='off';
            app.CheckButton.Visible='off';
            app.AnswerBox.Visible='on';
            app.TypedAns.Visible='on';
            CheckButtonPushed.Enable='off';
            app.GreenTick.Visible='off';
            app.WrongAnsImage.Visible='off';
            app.Lamp1.Color=[1 1 1];
            app.Lamp2.Color=[1 1 1];
            app.Lamp3.Color=[1 1 1];
            app.randbluebox.Value=[''];
            app.randbluebox.Enable='off';
            app.Bullets.Visible='off';
            app.Bullets2.Visible='off';
            app.Bullets3.Visible='off';
            app.Bullets5.Visible='off';
            app.Bullets4.Visible='off';
            app.Bullets6.Visible='off';
            app.Bullets7.Visible='off';
            app.Bullets8.Visible='off';
            app.Bullets9.Visible='off';
            app.Bullets10.Visible='off';
            app.Bullets11.Visible='off';
            app.Bullets12.Visible='off';
            app.Bullets13.Visible='off';
            app.Bullets14.Visible='off';
            app.Bullets15.Visible='off';
            app.Bullets16.Visible='off';
            app.Bullets17.Visible='off';
            app.Bullets18.Visible='off';
            app.Bullets19.Visible='off';
            app.Bullets20.Visible='off';
            app.Bullets21.Visible='off';
            app.Bullets22.Visible='off';
            app.Bullets23.Visible='off';
            app.SoundOn.Visible='on';
            app.NoSound.Visible='off';
            app.FinalTextDisplay.Visible='off';
            app.Proceed.Visible='off';
            app.SkipButton.Visible='off';
            app.BulletsFinalCount.Visible='off';
            app.FinalBattleRatingLabel.Visible='off';
            app.Bullets9.Visible='off';
            app.Background.Visible='off';
            app.Battleratingmessage.Visible='off';
            app.Battleratingmessage.Enable='off';
            app.TYPEHERELabel.Visible='on';
            app.randbluebox.Visible='on';
            app.AnswerBox.Visible='on';
            app.TypedAns.Visible='off';
            app.Timer.BackgroundColor=[1 1 1];
            [y,Fs]=audioread("techno-future-drone-main-9724.mp3");
            sound(y,Fs)

            
            %The above code ensure that the game is unplayable until the
            %start button is pressed
if app.Knob.Value=='L1'
            x=randi(9);
            y=randi(9);
            z=num2str(x);
            q=num2str(y);
            app.ans=x.*y;
            qtext=[z ' x ' q ' ']
            app.x8Label.Text=qtext;
elseif app.Knob.Value=='L2';
    x=randi(14);
            y=randi(14);
            app.ans=x.*y;
            z=num2str(x);
            q=num2str(y);
            qtext=[z ' x ' q ' ']
            app.x8Label.Text=qtext
else 
     x=randi(20);
            y=randi(20);
             z=num2str(x);
            q=num2str(y);
            app.ans=x.*y;
            qtext=[z ' x ' q ' ']
            app.x8Label.Text=qtext
end


    %This code generates random integers up to 9 to create a question that
    %can be solved
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
app.whiteback.Visible='off'
app.CosmicCalculationsLabel.Visible='off'
app.StartButton.Enable='off';
app.CheckButton.Enable='on';
app.TypedAns.Enable='on';
app.GreenTick.Visible='off'
app.WrongAnsImage.Visible='off';
app.AnswerBox.Enable='on';
app.Timer.BackgroundColor=[1 1 1];
app.randbluebox.Visible='off';
app.TypedAns.Visible='off';
app.SkipButton.Visible='on';
app.TYPEHERELabel.Visible='off';
app.StartButton.Visible='off';

timelimit=60;
timesup=0;
timerstart=tic;
while~timesup
    pause(0.1 );
    timepassed=toc(timerstart);
    app.Timer.Value=round(timelimit-timepassed);
    timesup=app.Timer.Value<=0;
if ~timesup
else  
    app.CheckButton.Enable='off';
    app.Retry.Visible='on';
    app.Proceed.Visible='on';

    app.BulletsFinalCount.Visible='on';
    app.BulletsFinalCount.Value=app.Bulletcount.Value;
    app.FinalTextDisplay.Visible='on';
    app.FinalBattleRatingLabel.Visible='on';
    app.AnswerBox.Enable='off';
    app.randbluebox.Visible='on';
    app.randbluebox.Value=' ';
    app.Background.Visible='on';
    app.Battleratingmessage.Visible='on';   
end

if app.Timer.Value<5;
    app.Timer.BackgroundColor=[1 0 0];
end

if app.Bulletcount.Value>=3;
    app.Bullets.Visible='on';
end
if app.Bulletcount.Value>=6;
    app.Bullets2.Visible='on';
end
if app.Bulletcount.Value>=9;
    app.Bullets3.Visible='on';
end
if app.Bulletcount.Value>=12;
    app.Bullets4.Visible='on';
end
if app.Bulletcount.Value>=15;
    app.Bullets5.Visible='on';
end
if app.Bulletcount.Value>=18;
    app.Bullets6.Visible='on';
end
if app.Bulletcount.Value>=21;
    app.Bullets7.Visible='on';
end
if app.Bulletcount.Value>=24;
    app.Bullets8.Visible='on';
end
if app.Bulletcount.Value>=27;
    app.Bullets9.Visible='on';
end
if app.Bulletcount.Value>=30;
    app.Bullets10.Visible='on';
end
if app.Bulletcount.Value>=33;
    app.Bullets11.Visible='on';
end
if app.Bulletcount.Value>=36;
    app.Bullets12.Visible='on';
end
if app.Bulletcount.Value>=39;
    app.Bullets13.Visible='on';
end
if app.Bulletcount.Value>=42;
    app.Bullets14.Visible='on';
end
if app.Bulletcount.Value>=45;
    app.Bullets15.Visible='on';
end
if app.Bulletcount.Value>=48;
    app.Bullets16.Visible='on';
end
if app.Bulletcount.Value>=51;
    app.Bullets17.Visible='on';
end
if app.Bulletcount.Value>=54;
    app.Bullets18.Visible='on';
end
if app.Bulletcount.Value>=57;
    app.Bullets19.Visible='on';
end
if app.Bulletcount.Value>=60;
    app.Bullets20.Visible='on';
end
if app.Bulletcount.Value>=63;
    app.Bullets21.Visible='on';
end
%The above code creates a countdown for the game
end


        end

        % Callback function: TypedAns, UIFigure
        function UIFigureKeyPress(app, event)
key=event.Key;
switch key
    case 'return';
        StartButtonPushed(app,event)
end


        end

        % Callback function: AnswerBox, CheckButton, UIFigure
        function CheckButtonPushed(app, event)

answer=str2double(app.AnswerBox.Value);       
if answer==app.ans&app.Knob.Value=='L1'
    app.CheckButton.BackgroundColor=[0 1 0];
    app.Bulletcount.Value=app.Bulletcount.Value+1;
    app.AnswerBox.Value='';
    app.WrongAnsImage.Visible='off';
    app.GreenTick.Visible='on';
    app.Lamp1.Color=[0 1 0];
    app.Lamp2.Color=[0 1 0];
    app.Lamp3.Color=[0 1 0];
    x=randi(9);
    y=randi(9);
    app.ans=x.*y;
    qtext=[num2str(x) ' x ' num2str(y) ' ']
    app.x8Label.Text=qtext
    [y,Fs]=audioread("ding-1-14705 (1).mp3")
    sound(y,Fs)
elseif answer==app.ans&app.Knob.Value=='L2';
    app.CheckButton.BackgroundColor=[0 1 0];
    app.Bulletcount.Value=app.Bulletcount.Value+2;
    app.AnswerBox.Value='';
    app.WrongAnsImage.Visible='off';
    app.GreenTick.Visible='on';
    app.Lamp1.Color=[0 1 0];
    app.Lamp2.Color=[0 1 0];
    app.Lamp3.Color=[0 1 0];
    x=randi(14);
    y=randi(14);
    app.ans=x.*y;
    z=num2str(x);
    q=num2str(y);
    app.ans=x.*y;
    qtext=[z ' x ' q ' ']
    app.x8Label.Text=qtext
    [y,Fs]=audioread("ding-1-14705 (1).mp3")
    sound(y,Fs)
elseif answer==app.ans&app.Knob.Value=='L3'
    app.CheckButton.BackgroundColor=[0 1 0];
    app.Bulletcount.Value=app.Bulletcount.Value+3;
    app.AnswerBox.Value='';
    app.WrongAnsImage.Visible='off';
    app.GreenTick.Visible='on';
    app.Lamp1.Color=[0 1 0];
    app.Lamp2.Color=[0 1 0];
    app.Lamp3.Color=[0 1 0];
    x=randi(20);
    y=randi(20);
    app.ans=x.*y;
    qtext=[num2str(x) ' x ' num2str(y) ' ']
    app.x8Label.Text=qtext
    [y,Fs]=audioread("ding-1-14705 (1).mp3")
    sound(y,Fs)
else
    app.CheckButton.BackgroundColor=[1 0 0];
    app.AnswerBox.Value='';
    app.Lamp1.Color=[1 0 0];
    app.Lamp2.Color=[1 0 0];
    app.Lamp3.Color=[1 0 0];
    app.WrongAnsImage.Visible='on'
    app.GreenTick.Visible='off'
    [y,Fs]=audioread("wrong-buzzer-6268.mp3")
    sound(y,Fs)
end

        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)

        end

        % Callback function
        function ReplayButtonPushed(app, event)
            app.startupFcn
           % app.TodaysBestScoreEditField
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)


        end

        % Image clicked function: SoundOn
        function SoundOnImageClicked(app, event)
            app.SoundOn.Visible=['off']
            app.NoSound.Visible='on'
            clear sound
        end

        % Image clicked function: NoSound
        function NoSoundImageClicked(app, event)
            app.SoundOn.Visible=['on']
            app.NoSound.Visible='off'
            [y,Fs]=audioread("techno-future-drone-main-9724.mp3");
            sound(y,Fs)

        end

        % Button pushed function: Retry
        function RetryButtonPushed(app, event)
                 app.startupFcn
        end

        % Button pushed function: Proceed
        function ProceedButtonPushed(app, event)

            app.NewGame=NewGame(app,app.BulletsFinalCount.Value)
            delete(app)

        end

        % Value changed function: BulletsFinalCount
        function BulletsFinalCountValueChanged(app, event)
            value = app.BulletsFinalCount.Value;
          
            
        end

        % Callback function
        function RedeembombsButtonPushed(app, event)

        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
if app.Knob.Value=='L1'
            x=randi(9);
            y=randi(9);
            z=num2str(x);
            q=num2str(y);
            app.ans=x.*y;
            qtext=[z ' x ' q ' ']
            app.x8Label.Text=qtext
elseif app.Knob.Value=='L2'
    x=randi(14);
            y=randi(14);
            app.ans=x.*y;
            z=num2str(x);
            q=num2str(y);
            qtext=[z ' x ' q ' ']
            app.x8Label.Text=qtext
else 
     x=randi(20);
            y=randi(20);
             z=num2str(x);
            q=num2str(y);
            app.ans=x.*y;
            qtext=[z ' x ' q ' ']
            app.x8Label.Text=qtext
end


    figure(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'spaceinterior.jpg';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.TextBorder = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Timer = uieditfield(app.UIFigure, 'numeric');
            app.BattleRatingLabel = uilabel(app.UIFigure);
            app.BattleRatingLabel.Text = 'Battle Rating';
            app.Bulletcount = uieditfield(app.UIFigure, 'numeric');
            app.TypedAns = uieditfield(app.UIFigure, 'numeric');
            app.TypedAns.ValueChangedFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.AnswerBox = uieditfield(app.UIFigure, 'text');
            app.AnswerBox.ValueChangedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.x8Label = uilabel(app.UIFigure);
            app.x8Label.Text = '5 x 8';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.Items = {'L1', 'L2', 'L3'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'L1';
            app.Lamp3 = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.Lamp1 = uilamp(app.UIFigure);
            app.GreenTick = uiimage(app.UIFigure);
            app.GreenTick.ImageSource = '992a73abbe8b44ed07ea10104a292604-removebg-preview.png';
            app.WrongAnsImage = uiimage(app.UIFigure);
            app.WrongAnsImage.ImageSource = 'acc4ae9c3c78590791f30624ca7a8f77-removebg-preview.png';
            app.Bullets9 = uiimage(app.UIFigure);
            app.Bullets9.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets2 = uiimage(app.UIFigure);
            app.Bullets2.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets3 = uiimage(app.UIFigure);
            app.Bullets3.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets5 = uiimage(app.UIFigure);
            app.Bullets5.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets4 = uiimage(app.UIFigure);
            app.Bullets4.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets6 = uiimage(app.UIFigure);
            app.Bullets6.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets7 = uiimage(app.UIFigure);
            app.Bullets7.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets8 = uiimage(app.UIFigure);
            app.Bullets8.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets = uiimage(app.UIFigure);
            app.Bullets.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.SoundOn = uiimage(app.UIFigure);
            app.SoundOn.ImageClickedFcn = createCallbackFcn(app, @SoundOnImageClicked, true);
            app.SoundOn.ImageSource = 'a5706847badbaa81aa8a11b14347a39f-removebg-preview.png';
            app.NoSound = uiimage(app.UIFigure);
            app.NoSound.ImageClickedFcn = createCallbackFcn(app, @NoSoundImageClicked, true);
            app.NoSound.ImageSource = 'nosound-removebg-preview.png';
            app.Bullets10 = uiimage(app.UIFigure);
            app.Bullets10.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets17 = uiimage(app.UIFigure);
            app.Bullets17.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets12 = uiimage(app.UIFigure);
            app.Bullets12.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets11 = uiimage(app.UIFigure);
            app.Bullets11.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets13 = uiimage(app.UIFigure);
            app.Bullets13.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets14 = uiimage(app.UIFigure);
            app.Bullets14.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets15 = uiimage(app.UIFigure);
            app.Bullets15.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets18 = uiimage(app.UIFigure);
            app.Bullets18.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets16 = uiimage(app.UIFigure);
            app.Bullets16.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets19 = uiimage(app.UIFigure);
            app.Bullets19.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets21 = uiimage(app.UIFigure);
            app.Bullets21.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets20 = uiimage(app.UIFigure);
            app.Bullets20.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets22 = uiimage(app.UIFigure);
            app.Bullets22.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.Bullets23 = uiimage(app.UIFigure);
            app.Bullets23.ImageSource = 'Screenshot_2023-04-20_010041-removebg-preview.png';
            app.randbluebox = uieditfield(app.UIFigure, 'text');
            app.TYPEHERELabel = uilabel(app.UIFigure);
            app.TYPEHERELabel.Text = 'TYPE HERE ';
            app.SELECTYOURDIFFICULTYLabel = uilabel(app.UIFigure);
            app.SELECTYOURDIFFICULTYLabel.Text = 'SELECT YOUR DIFFICULTY';
            app.Background = uieditfield(app.UIFigure, 'text');
            app.FinalTextDisplay = uitextarea(app.UIFigure);
            app.FinalTextDisplay.Value = {'Congrats, You have Acquired:'};
            app.FinalBattleRatingLabel = uilabel(app.UIFigure);
            app.FinalBattleRatingLabel.Text = 'Final Battle Credit';
            app.BulletsFinalCount = uieditfield(app.UIFigure, 'numeric');
            app.BulletsFinalCount.ValueChangedFcn = createCallbackFcn(app, @BulletsFinalCountValueChanged, true);
            app.Battleratingmessage = uitextarea(app.UIFigure);
            app.Battleratingmessage.Value = {'If you choose to proceed, we will direct you to the Galactic Battle Store where you can exchange your battle credits for the following. We have automatically converted your battle rating for bullets:'; ''; '-1 Battle Credit=1 Bullet'; '-5 Battle Credits=1 Bomb'; ''; ''};
            app.Proceed = uibutton(app.UIFigure, 'push');
            app.Proceed.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.Proceed.Text = 'Proceed to Battle';
            app.Retry = uibutton(app.UIFigure, 'push');
            app.Retry.ButtonPushedFcn = createCallbackFcn(app, @RetryButtonPushed, true);
            app.Retry.Text = 'Retry';
            app.whiteback = uieditfield(app.UIFigure, 'text');
            app.CosmicCalculationsLabel = uilabel(app.UIFigure);
            app.CosmicCalculationsLabel.Text = 'Cosmic Calculations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bulletload2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end